classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        FLUIDFLOWDETAILSLabel       matlab.ui.control.Label
        VinfEditFieldLabel          matlab.ui.control.Label
        VinfEditField               matlab.ui.control.NumericEditField
        ComputeButton               matlab.ui.control.Button
        ComputationStatusLampLabel  matlab.ui.control.Label
        ComputationStatusLamp       matlab.ui.control.Lamp
        AOAEditFieldLabel           matlab.ui.control.Label
        AOAEditField                matlab.ui.control.NumericEditField
        CenterPanel                 matlab.ui.container.Panel
        OutputPanelLabel            matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        InputPanelLabel             matlab.ui.control.Label
        PlotAerofoilButton          matlab.ui.control.Button
        sumEditFieldLabel           matlab.ui.control.Label
        sumEditField                matlab.ui.control.NumericEditField
        LiftEditFieldLabel          matlab.ui.control.Label
        LiftEditField               matlab.ui.control.EditField
        ClEditFieldLabel            matlab.ui.control.Label
        ClEditField                 matlab.ui.control.NumericEditField
        CmEditFieldLabel            matlab.ui.control.Label
        CmEditField                 matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        sum=0; % Description
    end
    
    methods (Access = public)
        
        
function [dataX,dataY] = LOAD_AIRFOIL_SELIG(app,fileName)

hdrlns = 1;
if (strcmp(fileName,'nasasc2-0714'))
    hdrlns = 3;
elseif (strcmp(fileName,'s1020'))
    hdrlns = 2;
end

fidAirfoil = fopen(['./Airfoil_DAT_Selig/' fileName '.dat']);               
dataBuffer = textscan(fidAirfoil,'%f %f','CollectOutput',1,...              
                                         'HeaderLines',hdrlns,...
                                         'Delimiter','');
dataX = dataBuffer{1}(:,1);                                                 
dataY = dataBuffer{1}(:,2);                                                 
fclose(fidAirfoil); 

% Delete any duplicate (0,0) lines (only need one)
dataArr     = [dataX dataY];
[~,ia,~]    = unique(dataArr,'rows','stable');                              
i           = true(size(dataArr,1),1);                                      
i(ia)       = false;                                                        
dataArr(i,:)= [];                                                           
dataX       = dataArr(:,1);                                               
dataY       = dataArr(:,2);                                               

% Close the airfoil by adding final point
if (dataY(1) ~= dataY(end))                                                 
    dataX(end+1) = dataX(1);                                                
    dataY(end+1) = dataY(1);                                                
end
end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            load = 'Airfoil';                                                            
            AoA  = app.AOAEditField.Value;
            Vinf = app.VinfEditField.Value; 
            fileName = 'naca2412';
            aoa=app.AOAEditField.Value;
            vinf=app.VinfEditField.Value;
            app.sumEditField.Value=aoa+vinf;
            
            [XB,YB]  = LOAD_AIRFOIL_SELIG(app,fileName);
            
            
            plot(app.UIAxes,XB,YB,'ko-');


            % Number of panels
numPan = length(XB)-2; 
XB(numPan+2,:)=[];
YB(numPan+2,:)=[];

% Check for direction of points

edge = zeros(numPan,1);                                                     
for i = 1:1:numPan                                                          
    edge(i) = (XB(i+1)-XB(i))*(YB(i+1)+YB(i));                              
end
sumEdge = sum(edge);                                                        

% If panels are CCW, flip them (don't if CW)
if (sumEdge < 0)                                                            
    fprintf('Points are counter-clockwise.  Flipping.\n');                  
    XB = flipud(XB);                                                        
    YB = flipud(YB);                                                        
elseif (sumEdge > 0)                                                        
    fprintf('Points are clockwise.  Not flipping.\n');                      
end


%% COMPUTE GEOMETRIC VARIABLES

% Find geometric quantities of airfoil
XC   = zeros(numPan,1);                                                     
YC   = zeros(numPan,1);                                                     
S    = zeros(numPan,1);                                                     
phiD = zeros(numPan,1);                                                     
for i = 1:1:numPan                                                         
    XC(i)   = 0.5*(XB(i)+XB(i+1));                                          
    YC(i)   = 0.5*(YB(i)+YB(i+1));                                          
    dx      = XB(i+1)-XB(i);                                                
    dy      = YB(i+1)-YB(i);                                                
    S(i)    = (dx^2 + dy^2)^0.5;                                            
	phiD(i) = atan2d(dy,dx);  
    if (phiD(i) < 0)                                                        % Make all panel angles positive [deg]
        phiD(i) = phiD(i) + 360;
    end
      
end

% Compute angle of panel normal w.r.t horizontal and include AoA
deltaD             = phiD + 90;                                             % Angle of panel normal
betaD              = deltaD - AoA;                                          % Angle of panel normal and AoA [deg]
betaD(betaD > 360) = betaD(betaD > 360) - 360;                              % Make sure angles aren't greater than 360 [deg]


phi  = phiD.*(pi/180);                                                      
beta = betaD.*(pi/180);                                                     


%% PLOTTING

T = linspace(0,360,1000);                                                  % Angle array to compute dashed circle
x = cosd(T);                                                                % Circle X points
y = sind(T);                                                                % Circle Y points

% Plot shape polygon with panel normal vectors
figure(21);                                                                 % Create figure
cla; hold on; grid off;                                                     % Get ready for plotting
set(gcf,'Color','White');                                                   % Set color to white
set(gca,'FontSize',12);                                                     % Set font size
fill(XB,YB,'k');                                                            % Plot polygon

for i = 1:1:numPan                                                          % Loop over all panels
    X(1) = XC(i);                                                           % Panel starting X point
    X(2) = XC(i) + S(i)*cosd(deltaD(i));                                    % Panel ending X point
    Y(1) = YC(i);                                                           % Panel starting Y point
    Y(2) = YC(i) + S(i)*sind(deltaD(i));                                    % Panel ending Y point
    if (i == 1)                                                             % For first panel
        p1 = plot(X,Y,'r-','LineWidth',2);                                  % Plot first panel normal vector
    elseif (i == 2)                                                         % For second panel
        p2 = plot(X,Y,'r-','LineWidth',2);                                  % Plot second panel normal vector
    else                                                                    % For every other panel
        plot(X,Y,'r-','LineWidth',2);                                       % Plot panel normal vector
    end
end
legend([p1,p2],{'Data 1','Data 2'});                                      % Add legend
xlabel('X Units');                                                          % Set X-label
ylabel('Y Units');                                                          % Set Y-label
axis equal;                                                                 % Set axes equal
zoom reset;         


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FLUIDFLOWDETAILSLabel = uilabel(app.LeftPanel);
            app.FLUIDFLOWDETAILSLabel.Text = 'FLUID FLOW DETAILS';
            app.VinfEditFieldLabel = uilabel(app.LeftPanel);
            app.VinfEditFieldLabel.Text = 'Vinf';
            app.VinfEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ComputeButton = uibutton(app.LeftPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.ComputationStatusLampLabel = uilabel(app.LeftPanel);
            app.ComputationStatusLampLabel.Text = 'Computation Status';
            app.ComputationStatusLamp = uilamp(app.LeftPanel);
            app.AOAEditFieldLabel = uilabel(app.LeftPanel);
            app.AOAEditFieldLabel.Text = 'AOA';
            app.AOAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CenterPanel = uipanel(app.GridLayout);
            app.OutputPanelLabel = uilabel(app.CenterPanel);
            app.OutputPanelLabel.Text = 'Output Panel';
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Aerofoil Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.CenterPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.CenterPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.InputPanelLabel = uilabel(app.RightPanel);
            app.InputPanelLabel.Text = 'Input Panel';
            app.PlotAerofoilButton = uibutton(app.RightPanel, 'push');
            app.PlotAerofoilButton.Text = 'Plot Aerofoil';
            app.sumEditFieldLabel = uilabel(app.RightPanel);
            app.sumEditFieldLabel.Text = 'sum';
            app.sumEditField = uieditfield(app.RightPanel, 'numeric');
            app.LiftEditFieldLabel = uilabel(app.RightPanel);
            app.LiftEditFieldLabel.Text = 'Lift';
            app.LiftEditField = uieditfield(app.RightPanel, 'text');
            app.ClEditFieldLabel = uilabel(app.RightPanel);
            app.ClEditFieldLabel.Text = 'Cl';
            app.ClEditField = uieditfield(app.RightPanel, 'numeric');
            app.CmEditFieldLabel = uilabel(app.RightPanel);
            app.CmEditFieldLabel.Text = 'Cm';
            app.CmEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end